classdef ADMESH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenFileMenu                    matlab.ui.container.Menu
        SaveAs14Menu                    matlab.ui.container.Menu
        SaveAsmatMenu                   matlab.ui.container.Menu
        SaveAskmlMenu                   matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        AttributeFile13Menu             matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        MeshMenu                        matlab.ui.container.Menu
        NOAACoastlineFileMenu           matlab.ui.container.Menu
        ElevationFileMenu               matlab.ui.container.Menu
        KMLFileMenu                     matlab.ui.container.Menu
        NLCDMenu                        matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        ExtractSubdomainMenu            matlab.ui.container.Menu
        MeshSubdomainMenu               matlab.ui.container.Menu
        PostprocessMenu                 matlab.ui.container.Menu
        InterpBathyMenu                 matlab.ui.container.Menu
        ReorderOpenChannelMenu          matlab.ui.container.Menu
        FillMeshSinksMenu               matlab.ui.container.Menu
        GetRoutingEdgesMenu             matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        ZoomIn                          matlab.ui.container.toolbar.ToggleTool
        ResetView                       matlab.ui.container.toolbar.PushTool
        Pan                             matlab.ui.container.toolbar.ToggleTool
        EditNode                        matlab.ui.container.toolbar.ToggleTool
        BadElemThreshold                matlab.ui.container.toolbar.PushTool
        BadElemZoom                     matlab.ui.container.toolbar.PushTool
        PinLocation                     matlab.ui.container.toolbar.ToggleTool
        InterpBathy                     matlab.ui.container.toolbar.PushTool
        EditCrossSection                matlab.ui.container.toolbar.ToggleTool
        ReorderOpenChannel              matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        CoordinateSystemDropDownLabel   matlab.ui.control.Label
        SpatialResolutionEditFieldLabel  matlab.ui.control.Label
        ViewMeshGenerationEditFieldLabel  matlab.ui.control.Label
        CoordinateSystemDropDown        matlab.ui.control.DropDown
        ViewMeshGenerationDropDown      matlab.ui.control.DropDown
        MeshGradingEditField            matlab.ui.control.NumericEditField
        MeshGradingEditFieldLabel       matlab.ui.control.Label
        MeshGradingDropDown             matlab.ui.control.DropDown
        DominateTideEditField           matlab.ui.control.NumericEditField
        DominateTideEditFieldLabel      matlab.ui.control.Label
        DominateTideDropDown            matlab.ui.control.DropDown
        ElevationGradientsEditFieldLabel  matlab.ui.control.Label
        ElevationGradientsEditField     matlab.ui.control.NumericEditField
        ElevationGradientsDropDown      matlab.ui.control.DropDown
        LocalFeatureSizeEditField       matlab.ui.control.NumericEditField
        LocalFeatureSizeEditFieldLabel  matlab.ui.control.Label
        LocalFeatureSizeDropDown        matlab.ui.control.DropDown
        BoundaryCurvatureEditField      matlab.ui.control.NumericEditField
        BoundaryCurvatureEditFieldLabel  matlab.ui.control.Label
        BoundaryCurvatureDropDown       matlab.ui.control.DropDown
        MinElementSizeEditField         matlab.ui.control.NumericEditField
        MaxElementSizemEditFieldLabel   matlab.ui.control.Label
        MinElementSizemLabel            matlab.ui.control.Label
        MaxElementSizeEditField         matlab.ui.control.NumericEditField
        SpatialResolutionEditField      matlab.ui.control.NumericEditField
        SpatialResolutionDropDown       matlab.ui.control.DropDown
        DisplayTab                      matlab.ui.container.Tab
        GridLayout2_2                   matlab.ui.container.GridLayout
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        ContoursDropDown                matlab.ui.control.DropDown
        ContoursDropDownLabel           matlab.ui.control.Label
        CrossSectionTab                 matlab.ui.container.Tab
        GridLayout2_3                   matlab.ui.container.GridLayout
        CSTrapezoidalButton             matlab.ui.control.Button
        MaxChannelWidthEditField        matlab.ui.control.NumericEditField
        ApproxChannelWidthLabel         matlab.ui.control.Label
        RetrieveBankEditField           matlab.ui.control.NumericEditField
        InterpCrossSectionButton        matlab.ui.control.Button
        Generate1DmeshonlyButton        matlab.ui.control.Button
        RetrieveBankButton              matlab.ui.control.Button
        ExtractChannelButton            matlab.ui.control.Button
        SmoothingRMSEEditField          matlab.ui.control.NumericEditField
        SmoothingRMSEmEditFieldLabel    matlab.ui.control.Label
        kEditField                      matlab.ui.control.NumericEditField
        kEditFieldLabel                 matlab.ui.control.Label
        AdvancedSettingsTab             matlab.ui.container.Tab
        GridLayout2_4                   matlab.ui.container.GridLayout
        qoldEditField                   matlab.ui.control.NumericEditField
        qoldEditFieldLabel              matlab.ui.control.Label
        niterEditFieldLabel             matlab.ui.control.Label
        niterEditField                  matlab.ui.control.NumericEditField
        geps_scaleEditField             matlab.ui.control.NumericEditField
        geps_scaleEditFieldLabel        matlab.ui.control.Label
        deltatEditField                 matlab.ui.control.NumericEditField
        deltatEditFieldLabel            matlab.ui.control.Label
        FscalEditField                  matlab.ui.control.NumericEditField
        MinElementSizemLabel_2          matlab.ui.control.Label
        ttolLabel                       matlab.ui.control.Label
        ttolEditField                   matlab.ui.control.NumericEditField
        ADMESHResultsPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ResultsBox                      matlab.ui.control.TextArea
        ProgressBarButton               matlab.ui.control.Button
        RunADMESHButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        FilePath
        PTS
        xyzFun
        MESH
        MinEQ = 0.3;
        xLimits = [0 1];
        yLimits = [0 1];
        per = 0;

%         ResolutionStatus
%         MaxElementSize
%         MinElementSize
%         CurvatureValue
%         LFSValue
%         ElevValue
%         TidalValue
%         GradingValue
%         ViewStatus
% 
%         CurvatureStatus
%         LFSStatus
%         ElevStatus
%         TidalStatus
%         GradingStatus

        DummyConstraint = 0;

        MasterAxis

        AdjustMeshNodes = 'off';
        SelectNode = nan;
        
        ViewAxes
        
        ElevationDataFilename
%         ExtractedChannelsFromLand
        TTC % TopoToolboxChannels
        
        PARAMS_bank
        CS_bankline
        CrossSection
        PI
        CSParams
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            restoredefaultpath;
            addpath(genpath('libraries'));
            CompileMEX; % Compile mex functions in admesh library
            %             movegui(app.UIFigure,'center');
            %             app.UIFigure.WindowState = 'maximized';
            %             rmpath('C:\Program Files\MATLAB\R2021a\toolbox\matlab\configtools');
            %             ADMESH_v_2(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            CloseADMESH(app);            
        end

        % Menu selected function: OpenFileMenu
        function OpenFileMenuSelected(app, event)
            OpenFile(app);
        end

        % Callback function
        function ZoomButtonValueChanged(app, event)

        end

        % Callback function
        function PanButtonValueChanged(app, event)

        end

        % Button pushed function: RunADMESHButton
        function RunADMESHButtonPushed(app, event)
            ADmeshRoutine(app);
        end

        % Callback function: ZoomIn
        function ZoomInClicked(app, event)

        end

        % On callback: ZoomIn
        function ZoomInOn(app, event)
            app.UIFigure.CurrentAxes = app.UIAxes;
            zoom(app.UIFigure,'on');
        end

        % Callback function
        function DominateTideSwitchDropDownOpening(app, event)
            
        end

        % Off callback: ZoomIn
        function ZoomInOff(app, event)
            app.UIFigure.CurrentAxes = app.UIAxes;
            zoom(app.UIFigure,'off');
        end

        % Callback function: ResetView
        function ResetViewClicked(app, event)
            %             app.UIFigure.CurrentAxes = app.UIAxes;
            %             zoom(app.UIFigure,'out');
            resetView(app);
        end

        % On callback: Pan
        function PanOn(app, event)
            app.UIFigure.CurrentAxes = app.UIAxes;
            pan(app.UIFigure,'on');
        end

        % Off callback: Pan
        function PanOff(app, event)
            app.UIFigure.CurrentAxes = app.UIAxes;
            pan(app.UIFigure,'off');
        end

        % Callback function: BadElemThreshold
        function BadElemThresholdClicked(app, event)
            DisplayBadElements(app);
        end

        % On callback: EditNode
        function EditNodeOn(app, event)
%             AdjustMeshNodes(app,[],'on');
                        app.AdjustMeshNodes = 'On';
        end

        % Off callback: EditNode
        function EditNodeOff(app, event)
%             AdjustMeshNodes(app,[],'off');
                        app.AdjustMeshNodes = 'Off';
        end

        % Callback function: BadElemZoom
        function BadElemZoomClicked(app, event)
            ZoomToBadElements(app);
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Callback function
        function Move(app, event)

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if strcmpi(app.AdjustMeshNodes,'on')
                %                 app.AdjustMeshNodes = 'off';
                moveNodes(app,[],'select node',[]);
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotionFcn(app, event)
            if strcmpi(app.AdjustMeshNodes,'on')
%                 moveNodes(app,[],'move nodes',[]);
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if strcmpi(app.AdjustMeshNodes,'on')
%                 moveNodes(app,[],'quit',[]);
%                 app.AdjustMeshNodes = 'off';
            end
        end

        % Menu selected function: SaveAs14Menu
        function SaveAs14MenuSelected(app, event)
            SaveMesh14File(app)
        end

        % Menu selected function: SaveAsmatMenu
        function SaveAsmatMenuSelected(app, event)
            SaveMatFile(app)
        end

        % Menu selected function: SaveAskmlMenu
        function SaveAskmlMenuSelected(app, event)
            SaveKMLFilev3(app)
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            ClearWindow(app)
        end

        % Menu selected function: MeshMenu
        function MeshMenuSelected(app, event)
            ImportMesh(app)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: DominateTideDropDown
        function DominateTideDropDownValueChanged(app, event)
            value = app.DominateTideDropDown.Value;
            if strcmpi(value,'on')
                app.DominateTideEditField.Editable = 1;
            else
                app.DominateTideEditField.Editable = 0;
            end
        end

        % Drop down opening function: ElevationGradientsDropDown
        function ElevationGradientsDropDownDropDownOpening(app, event)
            
        end

        % Menu selected function: ElevationFileMenu
        function ElevationFileMenuSelected(app, event)
            ImportElevationData(app);
        end

        % Menu selected function: NOAACoastlineFileMenu
        function NOAACoastlineFileMenuSelected(app, event)
            ImportNOAACoastline(app);
        end

        % Menu selected function: KMLFileMenu
        function KMLFileMenuSelected(app, event)
            ImportKMLFile(app);
        end

        % Menu selected function: ExtractSubdomainMenu
        function ExtractSubdomainMenuSelected(app, event)
            ExtractSubDomain(app);
        end

        % Menu selected function: MeshSubdomainMenu
        function MeshSubdomainMenuSelected(app, event)
            MeshSubDomain(app);
        end

        % Callback function
        function ContoursOnSelected(app, event)
            SetContourStatus(app,'On');
        end

        % Callback function
        function ContoursOffSelected(app, event)
            SetContourStatus(app,'Off');
        end

        % Callback function
        function ColormapLandSeaSelected(app, event)
            SetContourStatus(app,'Land & Sea');
        end

        % Callback function
        function ColormapJetSelected(app, event)
            SetContourStatus(app,'Jet');
        end

        % Callback function
        function ColormapParulaSelected(app, event)
            SetContourStatus(app,'Parula');
        end

        % Callback function: Pan
        function PanClicked(app, event)
            
        end

        % Value changed function: ContoursDropDown
        function ContoursDropDownValueChanged(app, event)
            SetContourStatus(app);
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            SetContourStatus(app);
        end

        % Callback function: InterpBathy
        function InterpBathyClicked(app, event)
            if ~isempty(app.MESH)
                app.MESH.Points(:,3) = app.xyzFun(app.MESH.Points(:,1),app.MESH.Points(:,2));
            end
        end

        % Callback function
        function AssignOpenOceanBoundaryConditionMenuSelected(app, event)
            AssignBC(app);
        end

        % Button pushed function: ExtractChannelButton
        function ExtractChannelButtonPushed(app, event)
            ExtractChannelsLandSubdomain(app,'firstcall');
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            ExtractChannelsLandSubdomain(app,'update');
        end

        % Value changed function: SpatialResolutionDropDown
        function SpatialResolutionDropDownValueChanged(app, event)
            UpdateSpatialResolutionEditField(app);
        end

        % Button pushed function: RetrieveBankButton
        function RetrieveBankButtonPushed(app, event)
            RetrieveChannelBanksOnConstraints(app);
        end

        % Button pushed function: Generate1DmeshonlyButton
        function Generate1DmeshonlyButtonPushed(app, event)
            app.DummyConstraint = 2;
            ADmeshRoutine(app);
        end

        % Button pushed function: InterpCrossSectionButton
        function InterpCrossSectionButtonPushed(app, event)
            InterpolateCrossSectionsOnConstraints(app);
        end

        % Off callback: EditCrossSection
        function EditCrossSectionOff(app, event)
            CloseEditCrossSection(app);
        end

        % Value changed function: CoordinateSystemDropDown
        function CoordinateSystemDropDownValueChanged(app, event)
            UpdateScalebar(app);            
        end

        % Menu selected function: NLCDMenu
        function NLCDMenuSelected(app, event)
            ImportNLCD(app);
        end

        % Callback function
        function FieldDropDownValueChanged(app, event)
            SetContourStatus(app);
        end

        % Menu selected function: AttributeFile13Menu
        function AttributeFile13MenuSelected(app, event)
            Export13File(app);
        end

        % Button pushed function: CSTrapezoidalButton
        function CSTrapezoidalButtonPushed(app, event)
            RetrieveCrossSectionParamsOnConstraints(app);
        end

        % Callback function: ReorderOpenChannel
        function ReorderOpenChannelClicked(app, event)
            ReorderOpenChannel(app);
        end

        % Menu selected function: InterpBathyMenu
        function InterpBathyMenuSelected(app, event)
            if ~isempty(app.MESH)
                app.MESH.Points(:,3) = app.xyzFun(app.MESH.Points(:,1),app.MESH.Points(:,2));
            end
        end

        % Menu selected function: ReorderOpenChannelMenu
        function ReorderOpenChannelMenuSelected(app, event)
            ReorderOpenChannel(app);
        end

        % Menu selected function: FillMeshSinksMenu
        function FillMeshSinksMenuSelected(app, event)
            FillMeshSink(app);
        end

        % Menu selected function: GetRoutingEdgesMenu
        function GetRoutingEdgesMenuSelected(app, event)
            GetRoutingEdges(app);
        end

        % Value changed function: ElevationGradientsDropDown
        function ElevationGradientsDropDownValueChanged(app, event)
            value = app.ElevationGradientsDropDown.Value;
            if strcmpi(value,'on')
                app.ElevationGradientsEditField.Editable = 1;
            else
                app.ElevationGradientsEditField.Editable = 0;
            end
        end

        % Value changed function: LocalFeatureSizeDropDown
        function LocalFeatureSizeDropDownValueChanged(app, event)
            value = app.LocalFeatureSizeDropDown.Value;
            if strcmpi(value,'on')
                app.LocalFeatureSizeEditField.Editable = 1;
            else
                app.LocalFeatureSizeEditField.Editable = 0;
            end
        end

        % Clicked callback: ColormapDropDown
        function ColormapDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ADMESH+ Advanced Mesh Generator | The C.H.I.L. ~ The Ohio State University';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotionFcn, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenFileMenu = uimenu(app.FileMenu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.OpenFileMenu.Text = 'Open File';
            app.SaveAs14Menu = uimenu(app.FileMenu);
            app.SaveAs14Menu.MenuSelectedFcn = createCallbackFcn(app, @SaveAs14MenuSelected, true);
            app.SaveAs14Menu.Text = 'Save As (.14)';
            app.SaveAsmatMenu = uimenu(app.FileMenu);
            app.SaveAsmatMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsmatMenuSelected, true);
            app.SaveAsmatMenu.Text = 'Save As (.mat)';
            app.SaveAskmlMenu = uimenu(app.FileMenu);
            app.SaveAskmlMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAskmlMenuSelected, true);
            app.SaveAskmlMenu.Text = 'Save As (.kml)';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.AttributeFile13Menu = uimenu(app.ExportMenu);
            app.AttributeFile13Menu.MenuSelectedFcn = createCallbackFcn(app, @AttributeFile13MenuSelected, true);
            app.AttributeFile13Menu.Text = 'Attribute File (.13)';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ClearMenu = uimenu(app.EditMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.MeshMenu = uimenu(app.ImportMenu);
            app.MeshMenu.MenuSelectedFcn = createCallbackFcn(app, @MeshMenuSelected, true);
            app.MeshMenu.Text = 'Mesh';
            app.NOAACoastlineFileMenu = uimenu(app.ImportMenu);
            app.NOAACoastlineFileMenu.MenuSelectedFcn = createCallbackFcn(app, @NOAACoastlineFileMenuSelected, true);
            app.NOAACoastlineFileMenu.Text = 'NOAA Coastline File';
            app.ElevationFileMenu = uimenu(app.ImportMenu);
            app.ElevationFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ElevationFileMenuSelected, true);
            app.ElevationFileMenu.Text = 'Elevation File';
            app.KMLFileMenu = uimenu(app.ImportMenu);
            app.KMLFileMenu.MenuSelectedFcn = createCallbackFcn(app, @KMLFileMenuSelected, true);
            app.KMLFileMenu.Text = 'KML File';
            app.NLCDMenu = uimenu(app.ImportMenu);
            app.NLCDMenu.MenuSelectedFcn = createCallbackFcn(app, @NLCDMenuSelected, true);
            app.NLCDMenu.Tooltip = {'Import National Land Cover Data'};
            app.NLCDMenu.Text = 'NLCD';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.ExtractSubdomainMenu = uimenu(app.ToolsMenu);
            app.ExtractSubdomainMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractSubdomainMenuSelected, true);
            app.ExtractSubdomainMenu.Text = 'Extract Sub-domain';
            app.MeshSubdomainMenu = uimenu(app.ToolsMenu);
            app.MeshSubdomainMenu.MenuSelectedFcn = createCallbackFcn(app, @MeshSubdomainMenuSelected, true);
            app.MeshSubdomainMenu.Text = 'Mesh Sub-domain';
            app.PostprocessMenu = uimenu(app.UIFigure);
            app.PostprocessMenu.Text = 'Post-process';
            app.InterpBathyMenu = uimenu(app.PostprocessMenu);
            app.InterpBathyMenu.MenuSelectedFcn = createCallbackFcn(app, @InterpBathyMenuSelected, true);
            app.InterpBathyMenu.Text = 'InterpBathy';
            app.ReorderOpenChannelMenu = uimenu(app.PostprocessMenu);
            app.ReorderOpenChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @ReorderOpenChannelMenuSelected, true);
            app.ReorderOpenChannelMenu.Text = 'ReorderOpenChannel';
            app.FillMeshSinksMenu = uimenu(app.PostprocessMenu);
            app.FillMeshSinksMenu.MenuSelectedFcn = createCallbackFcn(app, @FillMeshSinksMenuSelected, true);
            app.FillMeshSinksMenu.Text = 'Fill Mesh Sinks';
            app.GetRoutingEdgesMenu = uimenu(app.PostprocessMenu);
            app.GetRoutingEdgesMenu.MenuSelectedFcn = createCallbackFcn(app, @GetRoutingEdgesMenuSelected, true);
            app.GetRoutingEdgesMenu.Text = 'Get Routing Edges';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ZoomIn = uitoggletool(app.Toolbar);
            app.ZoomIn.Tooltip = {'Enable/disable zoom'};
            app.ZoomIn.ClickedCallback = createCallbackFcn(app, @ZoomInClicked, true);
            app.ZoomIn.Icon = 'tool_zoom_in.png';
            app.ZoomIn.OffCallback = createCallbackFcn(app, @ZoomInOff, true);
            app.ZoomIn.OnCallback = createCallbackFcn(app, @ZoomInOn, true);
            app.ResetView = uipushtool(app.Toolbar);
            app.ResetView.Tooltip = {'Reset to original view'};
            app.ResetView.ClickedCallback = createCallbackFcn(app, @ResetViewClicked, true);
            app.ResetView.Icon = fullfile(pathToMLAPP, 'libraries', 'gui library', 'toolbar_icon', 'double_arrow.png');
            app.Pan = uitoggletool(app.Toolbar);
            app.Pan.Tooltip = {'Enable/disable pan'};
            app.Pan.ClickedCallback = createCallbackFcn(app, @PanClicked, true);
            app.Pan.Icon = 'tool_hand.png';
            app.Pan.OffCallback = createCallbackFcn(app, @PanOff, true);
            app.Pan.OnCallback = createCallbackFcn(app, @PanOn, true);
            app.EditNode = uitoggletool(app.Toolbar);
            app.EditNode.Tooltip = {'Edit nodes'};
            app.EditNode.Icon = 'Edit_Node.png';
            app.EditNode.OffCallback = createCallbackFcn(app, @EditNodeOff, true);
            app.EditNode.OnCallback = createCallbackFcn(app, @EditNodeOn, true);
            app.BadElemThreshold = uipushtool(app.Toolbar);
            app.BadElemThreshold.Tooltip = {'Set element quality threshold'};
            app.BadElemThreshold.ClickedCallback = createCallbackFcn(app, @BadElemThresholdClicked, true);
            app.BadElemThreshold.Icon = 'bad_element.png';
            app.BadElemZoom = uipushtool(app.Toolbar);
            app.BadElemZoom.Tooltip = {'Zoom to low quality element'};
            app.BadElemZoom.ClickedCallback = createCallbackFcn(app, @BadElemZoomClicked, true);
            app.BadElemZoom.Icon = 'zoom_in_to_bad_element.png';
            app.PinLocation = uitoggletool(app.Toolbar);
            app.PinLocation.Tooltip = {'Flag location'};
            app.PinLocation.Icon = 'pin_icon.gif';
            app.InterpBathy = uipushtool(app.Toolbar);
            app.InterpBathy.Tooltip = {'Interpolate elevation to nodes'};
            app.InterpBathy.ClickedCallback = createCallbackFcn(app, @InterpBathyClicked, true);
            app.InterpBathy.Separator = 'on';
            app.EditCrossSection = uitoggletool(app.Toolbar);
            app.EditCrossSection.Tooltip = {'Edit bankline'};
            app.EditCrossSection.OffCallback = createCallbackFcn(app, @EditCrossSectionOff, true);
            app.ReorderOpenChannel = uipushtool(app.Toolbar);
            app.ReorderOpenChannel.ClickedCallback = createCallbackFcn(app, @ReorderOpenChannelClicked, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Visible = 'off';
            app.RunADMESHButton = uibutton(app.GridLayout, 'push');
            app.RunADMESHButton.ButtonPushedFcn = createCallbackFcn(app, @RunADMESHButtonPushed, true);
            app.RunADMESHButton.Text = 'Run ADMESH';
            app.ProgressBarButton = uibutton(app.GridLayout, 'push');
            app.ProgressBarButton.Text = {'Ready.'; ''};
            app.ADMESHResultsPanel = uipanel(app.GridLayout);
            app.ADMESHResultsPanel.Title = 'ADMESH Results';
            app.GridLayout3 = uigridlayout(app.ADMESHResultsPanel);
            app.ResultsBox = uitextarea(app.GridLayout3);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.SettingsTab);
            app.SpatialResolutionDropDown = uidropdown(app.GridLayout2);
            app.SpatialResolutionDropDown.Items = {'Low', 'Medium', 'High', 'High (2x)', 'High (3x)'};
            app.SpatialResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @SpatialResolutionDropDownValueChanged, true);
            app.SpatialResolutionDropDown.Value = 'Low';
            app.SpatialResolutionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpatialResolutionEditField.Editable = 'off';
            app.SpatialResolutionEditField.Enable = 'off';
            app.SpatialResolutionEditField.Visible = 'off';
            app.SpatialResolutionEditField.Value = 1;
            app.MaxElementSizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinElementSizemLabel = uilabel(app.GridLayout2);
            app.MinElementSizemLabel.Text = 'Min Element Size (m)';
            app.MaxElementSizemEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxElementSizemEditFieldLabel.Text = 'Max Element Size (m)';
            app.MinElementSizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BoundaryCurvatureDropDown = uidropdown(app.GridLayout2);
            app.BoundaryCurvatureDropDown.Items = {'On', 'Off'};
            app.BoundaryCurvatureDropDown.Value = 'On';
            app.BoundaryCurvatureEditFieldLabel = uilabel(app.GridLayout2);
            app.BoundaryCurvatureEditFieldLabel.Text = 'Boundary Curvature';
            app.BoundaryCurvatureEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BoundaryCurvatureEditField.Value = 20;
            app.LocalFeatureSizeDropDown = uidropdown(app.GridLayout2);
            app.LocalFeatureSizeDropDown.Items = {'On', 'Off'};
            app.LocalFeatureSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @LocalFeatureSizeDropDownValueChanged, true);
            app.LocalFeatureSizeDropDown.Value = 'Off';
            app.LocalFeatureSizeEditFieldLabel = uilabel(app.GridLayout2);
            app.LocalFeatureSizeEditFieldLabel.Text = 'Local Feature Size';
            app.LocalFeatureSizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LocalFeatureSizeEditField.Editable = 'off';
            app.ElevationGradientsDropDown = uidropdown(app.GridLayout2);
            app.ElevationGradientsDropDown.Items = {'On', 'Off'};
            app.ElevationGradientsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ElevationGradientsDropDownDropDownOpening, true);
            app.ElevationGradientsDropDown.ValueChangedFcn = createCallbackFcn(app, @ElevationGradientsDropDownValueChanged, true);
            app.ElevationGradientsDropDown.Value = 'Off';
            app.ElevationGradientsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ElevationGradientsEditField.Editable = 'off';
            app.ElevationGradientsEditFieldLabel = uilabel(app.GridLayout2);
            app.ElevationGradientsEditFieldLabel.Text = 'Elevation Gradients';
            app.DominateTideDropDown = uidropdown(app.GridLayout2);
            app.DominateTideDropDown.Items = {'Off', 'M2', 'S2', 'N2', 'K2'};
            app.DominateTideDropDown.ValueChangedFcn = createCallbackFcn(app, @DominateTideDropDownValueChanged, true);
            app.DominateTideDropDown.Value = 'Off';
            app.DominateTideEditFieldLabel = uilabel(app.GridLayout2);
            app.DominateTideEditFieldLabel.Text = 'Dominate Tide';
            app.DominateTideEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DominateTideEditField.Editable = 'off';
            app.MeshGradingDropDown = uidropdown(app.GridLayout2);
            app.MeshGradingDropDown.Items = {'On', 'Off'};
            app.MeshGradingDropDown.Value = 'On';
            app.MeshGradingEditFieldLabel = uilabel(app.GridLayout2);
            app.MeshGradingEditFieldLabel.Text = 'Mesh Grading';
            app.MeshGradingEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MeshGradingEditField.Value = 0.15;
            app.ViewMeshGenerationDropDown = uidropdown(app.GridLayout2);
            app.ViewMeshGenerationDropDown.Items = {'On', 'Off'};
            app.ViewMeshGenerationDropDown.Value = 'Off';
            app.CoordinateSystemDropDown = uidropdown(app.GridLayout2);
            app.CoordinateSystemDropDown.Items = {'Projected (m)', 'Unprojected (decimal degree)'};
            app.CoordinateSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateSystemDropDownValueChanged, true);
            app.CoordinateSystemDropDown.Value = 'Projected (m)';
            app.ViewMeshGenerationEditFieldLabel = uilabel(app.GridLayout2);
            app.ViewMeshGenerationEditFieldLabel.Text = 'View Mesh Generation';
            app.SpatialResolutionEditFieldLabel = uilabel(app.GridLayout2);
            app.SpatialResolutionEditFieldLabel.Text = 'Spatial Resolution';
            app.CoordinateSystemDropDownLabel = uilabel(app.GridLayout2);
            app.CoordinateSystemDropDownLabel.Text = 'Coordinate System';
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.GridLayout2_2 = uigridlayout(app.DisplayTab);
            app.ContoursDropDownLabel = uilabel(app.GridLayout2_2);
            app.ContoursDropDownLabel.Text = 'Contours';
            app.ContoursDropDown = uidropdown(app.GridLayout2_2);
            app.ContoursDropDown.Items = {'Off', 'Bathy/Topo', 'Manning''s n'};
            app.ContoursDropDown.ValueChangedFcn = createCallbackFcn(app, @ContoursDropDownValueChanged, true);
            app.ContoursDropDown.Value = 'Off';
            app.ColormapDropDownLabel = uilabel(app.GridLayout2_2);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout2_2);
            app.ColormapDropDown.Items = {'Land & Sea', 'Jet', 'Parula'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Enable = 'off';
            app.ColormapDropDown.ClickedFcn = createCallbackFcn(app, @ColormapDropDownClicked, true);
            app.ColormapDropDown.Value = 'Land & Sea';
            app.CrossSectionTab = uitab(app.TabGroup);
            app.CrossSectionTab.Title = 'CrossSection';
            app.GridLayout2_3 = uigridlayout(app.CrossSectionTab);
            app.kEditFieldLabel = uilabel(app.GridLayout2_3);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Value = 100000;
            app.SmoothingRMSEmEditFieldLabel = uilabel(app.GridLayout2_3);
            app.SmoothingRMSEmEditFieldLabel.Text = 'Smoothing RMSE (m)';
            app.SmoothingRMSEEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.SmoothingRMSEEditField.Value = 1;
            app.ExtractChannelButton = uibutton(app.GridLayout2_3, 'push');
            app.ExtractChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractChannelButtonPushed, true);
            app.ExtractChannelButton.Text = 'Extract Channel';
            app.RetrieveBankButton = uibutton(app.GridLayout2_3, 'push');
            app.RetrieveBankButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveBankButtonPushed, true);
            app.RetrieveBankButton.Text = 'Retrieve Bank';
            app.Generate1DmeshonlyButton = uibutton(app.GridLayout2_3, 'push');
            app.Generate1DmeshonlyButton.ButtonPushedFcn = createCallbackFcn(app, @Generate1DmeshonlyButtonPushed, true);
            app.Generate1DmeshonlyButton.Text = 'Generate 1D mesh only';
            app.InterpCrossSectionButton = uibutton(app.GridLayout2_3, 'push');
            app.InterpCrossSectionButton.ButtonPushedFcn = createCallbackFcn(app, @InterpCrossSectionButtonPushed, true);
            app.InterpCrossSectionButton.Text = 'Interp Cross Section';
            app.RetrieveBankEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.RetrieveBankEditField.Value = 2;
            app.ApproxChannelWidthLabel = uilabel(app.GridLayout2_3);
            app.ApproxChannelWidthLabel.Text = ' Max Channel Width (m)';
            app.MaxChannelWidthEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.MaxChannelWidthEditField.Value = 200;
            app.CSTrapezoidalButton = uibutton(app.GridLayout2_3, 'push');
            app.CSTrapezoidalButton.ButtonPushedFcn = createCallbackFcn(app, @CSTrapezoidalButtonPushed, true);
            app.CSTrapezoidalButton.Text = 'CS Trapezoidal';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.GridLayout2_4 = uigridlayout(app.AdvancedSettingsTab);
            app.ttolEditField = uieditfield(app.GridLayout2_4, 'numeric');
            app.ttolEditField.Value = 0.1;
            app.ttolLabel = uilabel(app.GridLayout2_4);
            app.ttolLabel.Text = 'ttol';
            app.MinElementSizemLabel_2 = uilabel(app.GridLayout2_4);
            app.MinElementSizemLabel_2.Text = 'Fscale';
            app.FscalEditField = uieditfield(app.GridLayout2_4, 'numeric');
            app.FscalEditField.Value = 1.2;
            app.deltatEditFieldLabel = uilabel(app.GridLayout2_4);
            app.deltatEditFieldLabel.Text = 'deltat';
            app.deltatEditField = uieditfield(app.GridLayout2_4, 'numeric');
            app.deltatEditField.Value = 0.2;
            app.geps_scaleEditFieldLabel = uilabel(app.GridLayout2_4);
            app.geps_scaleEditFieldLabel.Text = 'geps_scale';
            app.geps_scaleEditField = uieditfield(app.GridLayout2_4, 'numeric');
            app.geps_scaleEditField.Value = 0.01;
            app.niterEditField = uieditfield(app.GridLayout2_4, 'numeric');
            app.niterEditField.Value = 1000;
            app.niterEditFieldLabel = uilabel(app.GridLayout2_4);
            app.niterEditFieldLabel.Text = 'niter';
            app.qoldEditFieldLabel = uilabel(app.GridLayout2_4);
            app.qoldEditFieldLabel.Text = 'qold';
            app.qoldEditField = uieditfield(app.GridLayout2_4, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADMESH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end